classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        PlotoptionsPanel                matlab.ui.container.Panel
        BoundariesLabel                 matlab.ui.control.Label
        xminEditFieldLabel              matlab.ui.control.Label
        xminEditField                   matlab.ui.control.NumericEditField
        xmaxEditFieldLabel              matlab.ui.control.Label
        xmaxEditField                   matlab.ui.control.NumericEditField
        yminEditFieldLabel              matlab.ui.control.Label
        yminEditField                   matlab.ui.control.NumericEditField
        ymaxEditFieldLabel              matlab.ui.control.Label
        ymaxEditField                   matlab.ui.control.NumericEditField
        GridPointsLabel                 matlab.ui.control.Label
        mEditFieldLabel                 matlab.ui.control.Label
        mEditField                      matlab.ui.control.NumericEditField
        nEditFieldLabel                 matlab.ui.control.Label
        nEditField                      matlab.ui.control.NumericEditField
        ContourLevelsEditFieldLabel     matlab.ui.control.Label
        ContourLevelsEditField          matlab.ui.control.EditField
        PlotButton                      matlab.ui.control.Button
        ProduceasurfaceplotextrafigureCheckBox  matlab.ui.control.CheckBox
        SavecontourplottocontourpngCheckBox  matlab.ui.control.CheckBox
        ShowcontourlevellabelsCheckBox  matlab.ui.control.CheckBox
        ShoweigenvaluesofACheckBox      matlab.ui.control.CheckBox
        TabGroup                        matlab.ui.container.TabGroup
        LoadATab                        matlab.ui.container.Tab
        ReadAFrommatFileButton          matlab.ui.control.Button
        ReadAFromWorkspaceButton        matlab.ui.control.Button
        InputAManuallyTab               matlab.ui.container.Tab
        DimensionfornewAEditFieldLabel  matlab.ui.control.Label
        DimensionfornewAEditField       matlab.ui.control.NumericEditField
        ReadfromexpressionAEditFieldLabel  matlab.ui.control.Label
        ReadfromexpressionAEditField    matlab.ui.control.EditField
        UITable                         matlab.ui.control.Table
        DataofALabel                    matlab.ui.control.Label
        dimensionLabel                  matlab.ui.control.Label
        ResetDataButton                 matlab.ui.control.Button
        Produceaplotusingdef4extrafigureCheckBox  matlab.ui.control.CheckBox
        EpsilonLabel                    matlab.ui.control.Label
        EpsilonEditField                matlab.ui.control.NumericEditField
        NumofmatricesEditFieldLabel     matlab.ui.control.Label
        NumofmatricesEditField          matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        DimensionA = 3;        % Dimension of input matrix A
        A (:,:) { mustBeSquare, mustBeNumeric, mustBeNonempty, mustBeNonNan, mustBeFinite } = rand(3) + 1i*rand(3); % Input matrix A
        Running = false;        % Keeps track of the animation state
        xmin(1, 1) = -5; xmax(1, 1) = 5;         % Figure boundaries
        ymin(1, 1) = -5; ymax(1, 1) = 5;
        m(1, 1) = 50; n(1, 1) = 50;        % Numbers of grid point
        contourLevel(1,:) double { mustBePositive } = 20;      % Contour level
        saveOption;    % Option to save the contour plot
        surfOption;    % Option to show/hide the surface plot
        eigenOption;   % Option to show/hide the eigenvalues on contour plot
        labelOption;   % Option to show/hide the level labels
        def4plotOption;     % Option to plot using definition 4
        def4Epsilon (1,1) double {mustBePositive} = 0.5;     % Epsilon for definition 4
        def4NumOfMatrices (1,1) double {mustBeInteger, mustBePositive} = 100;   % Number of matrices to be generated for definition 4
    end
    
    methods (Access = private)
        
        function drawContour(app)
            % Main function used to produce the contour plot
            % Depend on the options, other figure can also be drawn
            if app.Running == true
                % Animation is currently running. Setting Running to false stops it.
                app.Running = false;
            else
                % Animation is not running, so we start it and change the button text to 'Stop'.
                app.Running = true;
                app.PlotButton.Text = 'Stop';
                
                x = linspace(app.xmin, app.xmax, app.m);
                y = linspace(app.ymin, app.ymax, app.n);
                [X,Y] = meshgrid(x,y);
                
                S = zeros(app.n,app.m);
                j = 1;
                labelLevelOption = 'off';
                
                % Show/hide contour level labels
                if (app.labelOption == true)
                    labelLevelOption = 'on';
                end
                
                % Plot and animate the contour
                while (j <= app.m) && (app.Running == true)
                    for k = 1:app.n
                        z = x(j) + 1i*y(k);
                        S(k,j) = svds(z*eye(app.DimensionA) - app.A, 1, 'smallest');
                    end
                    contour(app.UIAxes, X, Y, S, app.contourLevel, 'ShowText', labelLevelOption);
                    drawnow;
                    j = j + 1;
                end
                
                % Add eigenvalues to contour plot
                if (app.eigenOption == true)
                    e = eig(app.A);
                    i = 1;
                    
                    while (i <= length(e))
                        if ((real(e(i,:)) > app.xmax) || (real(e(i,:)) < app.xmin) || (imag(e(i,:)) > app.ymax) || (imag(e(i,:)) < app.ymin))
                            e(i) = [];
                        else
                            i = i+1;
                        end
                    end
                    
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes, e, 'o', 'MarkerFaceColor', 'r', 'MarkerEdgeColor', 'k');
                    hold(app.UIAxes, 'off');
                end
                
                % Save contour figure to file 'contour.png'
                if (app.saveOption == true)
                    h = figure('Visible', 'off');
                    contour(X, Y, S, app.contourLevel, 'ShowText', labelLevelOption);
                    
                    hold on;
                    if (app.eigenOption == true)
                        plot(e, 'o', 'MarkerFaceColor', 'r', 'MarkerEdgeColor', 'k');
                    end
                    hold off;
                    
                    saveas(h, 'contour', 'png');
                end
                
                % Produce surface plot
                if (app.surfOption == true)
                    figure('Name', 'Surface Plot');
                    surf(X, Y, S);
                end
                
                % Produce a plot using Definition 4.
                % The idea is to generate random complex matrices, modify them to have a norm
                % between 0 and eps, and plot their eigenvalues.
                if app.def4plotOption
                    num_of_mat = app.def4NumOfMatrices;
                    dim = app.DimensionA;
                    eps = app.def4Epsilon;
                    
                    eigenvalues = zeros(1,dim*num_of_mat); % Preallocating an empty array for the eigenvalues
                    
                    for index_var = 1:dim:dim*num_of_mat
                        E = (rand(dim)-0.5) + 1i*(rand(dim)-0.5); % Generating a matrix with random complex numbers
                        E = rand * eps/norm(E) * E; % Multiplying the matrix with eps/norm(E) to make the norm exactly eps
                                                    % Multiplying again with rand to make the norm between 0 and eps.
                        eigs = eig(app.A + E);
                        eigenvalues(index_var:index_var + dim-1) = eigs;
                    end
                    figure('Name', 'Plot using definition 4');
                    plot(real(eigenvalues), imag(eigenvalues), 'Color','k','MarkerSize',1,'Marker','.','LineStyle','none');
                end
                
                app.PlotButton.Text = 'Plot';
                app.Running = false;
            end
        end
               
        function matrix = read_A_from_workspace(app)
            % A function for reading variable 'A' from the workspace
            try
                matrix = evalin('base','A');
            catch
                msgID = 'app:read_A_from_workspace';
                msg = 'Could not read the matrix A from the workspace';
                Exception = MException(msgID, msg);
                throw(Exception);
            end
        end
        
        function matrix = read_A_from_file(app)
            % A function for reading variable 'A' from a file
            try
                fileName = uigetfile('*.mat');
                file = load(fileName);
                matrix = file.A;
            catch
                msgID = 'app:read_A_from_file';
                msg = 'Could not read the matrix A from the file';
                Exception = MException(msgID, msg);
                throw(Exception);
            end
        end
        
        function var = read_variable_from_string(app, str)
            % A function for reading a variable from a string
            try
                var = eval(str);
            catch
                msgID = 'app:read_variable_from_expression';
                msg = 'Invalid expression';
                Exception = MException(msgID, msg);
                throw(Exception);
            end
        end
        
        function set_A(app, matrix)
            % A function for setting the matrix A and related properties
            try
                app.A = matrix;
                app.UITable.Data = app.A;
                app.DimensionA = size(app.A, 1);
                app.DimensionfornewAEditField.Value = app.DimensionA;
                app.dimensionLabel.Text = sprintf('(%dx%d)', app.DimensionA, app.DimensionA);
                app.ReadfromexpressionAEditField.Value = '';
            catch Exception
                throw(Exception);
            end
        end
        
        function display_Error(app, Exception)
            % A private method for displaying the error messages in a dialogue box.
            uialert(app.UIFigure, Exception.message, 'Something went haywire!','Icon','Warning');
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Start-up function initiates the default values of properties to graphical components
            
            % Initiate dimension for A
            app.DimensionfornewAEditField.Value = app.DimensionA;
            app.dimensionLabel.Text = sprintf('(%dx%d)', app.DimensionA, app.DimensionA);
            
            % Load A to table
            app.UITable.Data = app.A;
            app.UITable.ColumnEditable = true;
            
            % Initiate boundaries
            app.xminEditField.Value = app.xmin;
            app.xmaxEditField.Value = app.xmax;
            app.yminEditField.Value = app.ymin;
            app.ymaxEditField.Value = app.ymax;
            
            % Initiate numbers of grid point field values
            app.mEditField.Value = app.m;
            app.nEditField.Value = app.n;
            
            % Initiate contour level field value
            app.ContourLevelsEditField.Value = num2str(app.contourLevel);
            
            app.EpsilonEditField.Value = app.def4Epsilon;
            app.NumofmatricesEditField.Value = app.def4NumOfMatrices;
        end

        % Value changed function: ContourLevelsEditField
        function ContourLevelsEditFieldValueChanged(app, event)
            value = app.ContourLevelsEditField.Value;
            try
                levels = read_variable_from_string(app,value);
                app.contourLevel = levels;
            catch Exception
                display_Error(app,Exception);
            end
            
        end

        % Value changed function: DimensionfornewAEditField
        function DimensionfornewAEditFieldValueChanged(app, event)
            value = app.DimensionfornewAEditField.Value;
            app.DimensionA = value;
            app.dimensionLabel.Text = sprintf('(%dx%d)', app.DimensionA, app.DimensionA);
            app.ResetDataButtonPushed;
        end

        % Value changed function: EpsilonEditField
        function EpsilonEditFieldValueChanged(app, event)
            value = app.EpsilonEditField.Value;
            app.def4Epsilon = value;
        end

        % Value changed function: NumofmatricesEditField
        function NumofmatricesEditFieldValueChanged(app, event)
            value = app.NumofmatricesEditField.Value;
            app.def4NumOfMatrices = value;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            drawContour(app);
        end

        % Value changed function: 
        % Produceaplotusingdef4extrafigureCheckBox
        function Produceaplotusingdef4extrafigureCheckBoxValueChanged(app, event)
            value = app.Produceaplotusingdef4extrafigureCheckBox.Value;
            app.def4plotOption = value;
            if value
                app.EpsilonEditField.Enable = 'on';
                app.NumofmatricesEditField.Enable= 'on';
            else
                app.EpsilonEditField.Enable = 'off';
                app.NumofmatricesEditField.Enable= 'off';
            end
        end

        % Value changed function: 
        % ProduceasurfaceplotextrafigureCheckBox
        function ProduceasurfaceplotextrafigureCheckBoxValueChanged(app, event)
            value = app.ProduceasurfaceplotextrafigureCheckBox.Value;
            app.surfOption = value;
        end

        % Button pushed function: ReadAFromWorkspaceButton
        function ReadAFromWorkspaceButtonPushed(app, event)
            try
                matrix = read_A_from_workspace(app);
                set_A(app, matrix);
            catch Exception
                display_Error(app,Exception);
            end
        end

        % Button pushed function: ReadAFrommatFileButton
        function ReadAFrommatFileButtonPushed(app, event)
            try
                matrix = read_A_from_file(app);
                set_A(app, matrix);
            catch Exception
                display_Error(app, Exception);
            end
        end

        % Value changed function: ReadfromexpressionAEditField
        function ReadfromexpressionAEditFieldValueChanged(app, event)
            value = app.ReadfromexpressionAEditField.Value;
            try
                matrix = read_variable_from_string(app, value);
                set_A(app, matrix);
            catch Exception
                display_Error(app,Exception);
            end
        end

        % Button pushed function: ResetDataButton
        function ResetDataButtonPushed(app, event)
            app.A = zeros(app.DimensionA);
            app.UITable.Data = app.A;
            app.ReadfromexpressionAEditField.Value = '';
        end

        % Value changed function: SavecontourplottocontourpngCheckBox
        function SavecontourplottocontourpngCheckBoxValueChanged(app, event)
            value = app.SavecontourplottocontourpngCheckBox.Value;
            app.saveOption = value;
        end

        % Value changed function: ShowcontourlevellabelsCheckBox
        function ShowcontourlevellabelsCheckBoxValueChanged(app, event)
            value = app.ShowcontourlevellabelsCheckBox.Value;
            app.labelOption = value;
        end

        % Value changed function: ShoweigenvaluesofACheckBox
        function ShoweigenvaluesofACheckBoxValueChanged(app, event)
            value = app.ShoweigenvaluesofACheckBox.Value;
            app.eigenOption = value;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            value = app.UITable.Data;
            try
                app.A = value;
            catch Exception
                app.UITable.Data = app.A;
                uialert(app.UIFigure, 'Value cannot be NaN. Change was not applied.', Exception.identifier);
            end
        end

        % Value changed function: mEditField
        function mEditFieldValueChanged(app, event)
            value = app.mEditField.Value;
            app.m = value;
        end

        % Value changed function: nEditField
        function nEditFieldValueChanged(app, event)
            value = app.nEditField.Value;
            app.n = value;
        end

        % Value changed function: xmaxEditField
        function xmaxEditFieldValueChanged(app, event)
            value = app.xmaxEditField.Value;
            if (value <= app.xmin)     % If the input value <= xmin, reset the field value.
                app.xmaxEditField.Value = app.xmax;
                msgID = 'app:figure_boundary_edit';
                msg = 'x min must be smaller than x max. Change was not applied.';
                Exception = MException(msgID, msg);
                display_Error(app, Exception);
            else
                app.xmax = value;
            end
        end

        % Value changed function: xminEditField
        function xminEditFieldValueChanged(app, event)
            value = app.xminEditField.Value;
            if (value >= app.xmax)     % If the input value >= xmax, reset the field value.
                app.xminEditField.Value = app.xmin;
                msgID = 'app:figure_boundary_edit';
                msg = 'x min must be smaller than x max. Change was not applied.';
                Exception = MException(msgID, msg);
                display_Error(app, Exception);
            else
                app.xmin = value;
            end
        end

        % Value changed function: ymaxEditField
        function ymaxEditFieldValueChanged(app, event)
            value = app.ymaxEditField.Value;
            if (value <= app.ymin)     % If the input value <= ymin, reset the field value.
                app.ymaxEditField.Value = app.ymax;
                msgID = 'app:figure_boundary_edit';
                msg = 'y min must be smaller than y max. Change was not applied.';
                Exception = MException(msgID, msg);
                display_Error(app, Exception);
            else
                app.ymax = value;
            end
        end

        % Value changed function: yminEditField
        function yminEditFieldValueChanged(app, event)
            value = app.yminEditField.Value;
            if (value >= app.ymax)     % If the input value >= ymax, reset the field value.
                app.yminEditField.Value = app.ymin;
                msgID = 'app:figure_boundary_edit';
                msg = 'y min must be smaller than y max. Change was not applied.';
                Exception = MException(msgID, msg);
                display_Error(app, Exception);
            else
                app.ymin = value;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Pseudospectrum of Square Matrix A')
            xlabel(app.UIAxes, 'Real(z)')
            ylabel(app.UIAxes, 'Imag(z)')
            app.PlotoptionsPanel = uipanel(app.UIFigure);
            app.PlotoptionsPanel.Title = 'Plot options';
            app.BoundariesLabel = uilabel(app.PlotoptionsPanel);
            app.BoundariesLabel.Text = 'Boundaries';
            app.xminEditFieldLabel = uilabel(app.PlotoptionsPanel);
            app.xminEditFieldLabel.Text = 'x min';
            app.xminEditField = uieditfield(app.PlotoptionsPanel, 'numeric');
            app.xminEditField.ValueChangedFcn = createCallbackFcn(app, @xminEditFieldValueChanged, true);
            app.xminEditField.Value = -5;
            app.xmaxEditFieldLabel = uilabel(app.PlotoptionsPanel);
            app.xmaxEditFieldLabel.Text = 'x max';
            app.xmaxEditField = uieditfield(app.PlotoptionsPanel, 'numeric');
            app.xmaxEditField.ValueChangedFcn = createCallbackFcn(app, @xmaxEditFieldValueChanged, true);
            app.xmaxEditField.Value = 5;
            app.yminEditFieldLabel = uilabel(app.PlotoptionsPanel);
            app.yminEditFieldLabel.Text = 'y min';
            app.yminEditField = uieditfield(app.PlotoptionsPanel, 'numeric');
            app.yminEditField.ValueChangedFcn = createCallbackFcn(app, @yminEditFieldValueChanged, true);
            app.yminEditField.Value = -5;
            app.ymaxEditFieldLabel = uilabel(app.PlotoptionsPanel);
            app.ymaxEditFieldLabel.Text = 'y max';
            app.ymaxEditField = uieditfield(app.PlotoptionsPanel, 'numeric');
            app.ymaxEditField.ValueChangedFcn = createCallbackFcn(app, @ymaxEditFieldValueChanged, true);
            app.ymaxEditField.Value = 5;
            app.GridPointsLabel = uilabel(app.PlotoptionsPanel);
            app.GridPointsLabel.Text = 'Grid Points';
            app.mEditFieldLabel = uilabel(app.PlotoptionsPanel);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.PlotoptionsPanel, 'numeric');
            app.mEditField.ValueChangedFcn = createCallbackFcn(app, @mEditFieldValueChanged, true);
            app.mEditField.Limits = [1 Inf];
            app.mEditField.RoundFractionalValues = 'on';
            app.mEditField.ValueDisplayFormat = '%.0f';
            app.mEditField.Value = 100;
            app.nEditFieldLabel = uilabel(app.PlotoptionsPanel);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.PlotoptionsPanel, 'numeric');
            app.nEditField.ValueChangedFcn = createCallbackFcn(app, @nEditFieldValueChanged, true);
            app.nEditField.Limits = [1 Inf];
            app.nEditField.RoundFractionalValues = 'on';
            app.nEditField.ValueDisplayFormat = '%.0f';
            app.nEditField.Value = 100;
            app.ContourLevelsEditFieldLabel = uilabel(app.PlotoptionsPanel);
            app.ContourLevelsEditFieldLabel.Text = 'Contour Levels = ';
            app.ContourLevelsEditField = uieditfield(app.PlotoptionsPanel, 'text');
            app.ContourLevelsEditField.ValueChangedFcn = createCallbackFcn(app, @ContourLevelsEditFieldValueChanged, true);
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ProduceasurfaceplotextrafigureCheckBox = uicheckbox(app.UIFigure);
            app.ProduceasurfaceplotextrafigureCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProduceasurfaceplotextrafigureCheckBoxValueChanged, true);
            app.ProduceasurfaceplotextrafigureCheckBox.Text = 'Produce a surface plot (extra figure)';
            app.SavecontourplottocontourpngCheckBox = uicheckbox(app.UIFigure);
            app.SavecontourplottocontourpngCheckBox.ValueChangedFcn = createCallbackFcn(app, @SavecontourplottocontourpngCheckBoxValueChanged, true);
            app.SavecontourplottocontourpngCheckBox.Text = 'Save contour plot to ''contour.png''';
            app.ShowcontourlevellabelsCheckBox = uicheckbox(app.UIFigure);
            app.ShowcontourlevellabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowcontourlevellabelsCheckBoxValueChanged, true);
            app.ShowcontourlevellabelsCheckBox.Text = 'Show contour level labels';
            app.ShoweigenvaluesofACheckBox = uicheckbox(app.UIFigure);
            app.ShoweigenvaluesofACheckBox.ValueChangedFcn = createCallbackFcn(app, @ShoweigenvaluesofACheckBoxValueChanged, true);
            app.ShoweigenvaluesofACheckBox.Text = 'Show eigenvalues of A';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadATab = uitab(app.TabGroup);
            app.LoadATab.Title = 'Load A';
            app.ReadAFrommatFileButton = uibutton(app.LoadATab, 'push');
            app.ReadAFrommatFileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadAFrommatFileButtonPushed, true);
            app.ReadAFrommatFileButton.Text = 'Read A From .mat File';
            app.ReadAFromWorkspaceButton = uibutton(app.LoadATab, 'push');
            app.ReadAFromWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ReadAFromWorkspaceButtonPushed, true);
            app.ReadAFromWorkspaceButton.Text = 'Read A From Workspace';
            app.InputAManuallyTab = uitab(app.TabGroup);
            app.InputAManuallyTab.Title = 'Input A Manually';
            app.DimensionfornewAEditFieldLabel = uilabel(app.InputAManuallyTab);
            app.DimensionfornewAEditFieldLabel.Text = 'Dimension for new A';
            app.DimensionfornewAEditField = uieditfield(app.InputAManuallyTab, 'numeric');
            app.DimensionfornewAEditField.UpperLimitInclusive = 'off';
            app.DimensionfornewAEditField.ValueChangedFcn = createCallbackFcn(app, @DimensionfornewAEditFieldValueChanged, true);
            app.DimensionfornewAEditField.Limits = [1 Inf];
            app.DimensionfornewAEditField.RoundFractionalValues = 'on';
            app.DimensionfornewAEditField.ValueDisplayFormat = '%.0f';
            app.DimensionfornewAEditField.Value = 3;
            app.ReadfromexpressionAEditFieldLabel = uilabel(app.InputAManuallyTab);
            app.ReadfromexpressionAEditFieldLabel.Text = 'Read from expression, A=';
            app.ReadfromexpressionAEditField = uieditfield(app.InputAManuallyTab, 'text');
            app.ReadfromexpressionAEditField.ValueChangedFcn = createCallbackFcn(app, @ReadfromexpressionAEditFieldValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {''; ''; ''; ''};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.DataofALabel = uilabel(app.UIFigure);
            app.DataofALabel.Text = 'Data of A';
            app.dimensionLabel = uilabel(app.UIFigure);
            app.dimensionLabel.Text = 'dimension';
            app.ResetDataButton = uibutton(app.UIFigure, 'push');
            app.ResetDataButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDataButtonPushed, true);
            app.ResetDataButton.Text = 'Reset Data';
            app.Produceaplotusingdef4extrafigureCheckBox = uicheckbox(app.UIFigure);
            app.Produceaplotusingdef4extrafigureCheckBox.ValueChangedFcn = createCallbackFcn(app, @Produceaplotusingdef4extrafigureCheckBoxValueChanged, true);
            app.Produceaplotusingdef4extrafigureCheckBox.Text = 'Produce a plot using def. 4 (extra figure)';
            app.EpsilonLabel = uilabel(app.UIFigure);
            app.EpsilonLabel.Text = 'Epsilon:';
            app.EpsilonEditField = uieditfield(app.UIFigure, 'numeric');
            app.EpsilonEditField.LowerLimitInclusive = 'off';
            app.EpsilonEditField.UpperLimitInclusive = 'off';
            app.EpsilonEditField.ValueChangedFcn = createCallbackFcn(app, @EpsilonEditFieldValueChanged, true);
            app.EpsilonEditField.Limits = [0 Inf];
            app.EpsilonEditField.Enable = 'off';
            app.EpsilonEditField.Value = 1;
            app.NumofmatricesEditFieldLabel = uilabel(app.UIFigure);
            app.NumofmatricesEditFieldLabel.Text = 'Num of matrices:';
            app.NumofmatricesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumofmatricesEditField.UpperLimitInclusive = 'off';
            app.NumofmatricesEditField.ValueChangedFcn = createCallbackFcn(app, @NumofmatricesEditFieldValueChanged, true);
            app.NumofmatricesEditField.Limits = [1 Inf];
            app.NumofmatricesEditField.RoundFractionalValues = 'on';
            app.NumofmatricesEditField.ValueDisplayFormat = '%.0f';
            app.NumofmatricesEditField.Enable = 'off';
            app.NumofmatricesEditField.Value = 100;
        end
    end

    methods (Access = public)

        % Construct app
        function app = project

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end